classdef robotControlGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DynamicRoboticsControlCentreUIFigure  matlab.ui.Figure
        ControllerPanel             matlab.ui.container.Panel
        UploadControllerButton      matlab.ui.control.Button
        PEditFieldLabel             matlab.ui.control.Label
        PEditField                  matlab.ui.control.NumericEditField
        IEditFieldLabel             matlab.ui.control.Label
        IEditField                  matlab.ui.control.NumericEditField
        DEditFieldLabel             matlab.ui.control.Label
        DEditField                  matlab.ui.control.NumericEditField
        uploadControllerStatus      matlab.ui.control.Label
        LiveDataPanel               matlab.ui.container.Panel
        UIAxesGyro                  matlab.ui.control.UIAxes
        UIAxesGyroTime              matlab.ui.control.UIAxes
        DeviceConnectionPanel       matlab.ui.container.Panel
        ConnectButton               matlab.ui.control.Button
        PleaseConnecttoadeviceLamp  matlab.ui.control.Lamp
        ConnectionStatusText        matlab.ui.control.Label
        BatteryLabelDescriptor      matlab.ui.control.Label
        BatteryLevel                matlab.ui.control.Label
        ConnectionStatusText_2      matlab.ui.control.Label
        DataloggingPanel            matlab.ui.container.Panel
        StartDatalogButton          matlab.ui.control.Button
        DataLogInfoText             matlab.ui.control.Label
        SaveasExcelsheetButton      matlab.ui.control.Button
        RedownloadButton            matlab.ui.control.Button
        ZeropointPanel              matlab.ui.container.Panel
        SetZeroButton               matlab.ui.control.Button
        SetPointEditField           matlab.ui.control.NumericEditField
        setZeroStatus               matlab.ui.control.Label
        MotorsPanel                 matlab.ui.container.Panel
        StopButton                  matlab.ui.control.Button
        StartButton                 matlab.ui.control.Button
    end


    properties (Access = private)
        % Serial variables
        serial_port;                % Arduino/bluetooth serial port
        serial_read_timer;          % 10Hz timer used to grab data from the bluetooth device & update graphs 
        
        % Battery variables
        battery_timer;              % Timer to update battery voltage that runs every 60 seconds
        
        all_roll = [];              % Description
        all_pitch = [];             % Description
        data_log = false;           % Description
        
        % Commands
        get_pitch_cmd = uint8(0);
        get_roll_cmd = uint8(1);
        get_yaw_cmd = uint8(2);
        set_zero_cmd = uint8(3);
        set_pid_cmd = uint8(4);
        stop_cmd = uint8(5); 
        start_cmd = uint8(6);
        start_datalog_cmd = uint8(7);
        get_battery_cmd = uint8(8);
        get_id_cmd = uint8(9); 
        download_data_cmd = uint8(11);
        get_pitch_roll_cmd = uint8(12);

        % Data log
        data_log_table;             % table
        num_readings = 200;               % Description

        device_id                   % ID of device received on connection
    end

    methods (Access = private)        

        function results = plotCircle(app, axes, radius, colour)
            x = 0;
            y = 0;
            ang=0:0.01:2*pi; 
            xp=radius*cos(ang);
            yp=radius*sin(ang);
            plot(axes, x+xp,y+yp, 'Color', colour);
        end
        
        function results = plotGyroTarget(app)
        
            plotCircle(app, app.UIAxesGyro, 45, [1 0 0]);
            plotCircle(app, app.UIAxesGyro, 5, [0 0.8 0]);
        end
      
        function results = updateGyroTimePlot(app)
           plot(app.UIAxesGyroTime, [app.all_roll' app.all_pitch']);
           legend(app.UIAxesGyroTime,'Roll','Pitch');
        end
        
        function results = updateGyroPlot(app, x, y)
            cla(app.UIAxesGyro);
            plotGyroTarget(app);
            plot(app.UIAxesGyro, x, y, 'O', 'Color', [0 0 1]);
            text(app.UIAxesGyro, x+5,y, strcat(num2str(x), ', ', num2str(y)));
        end
        
        %==============================================================%
        %                   serialReadTimerFunction
        % - Function to read gyro data 
        %==============================================================%
        function serialReadTimerFunction(app,~,~)
            
            fwrite(app.serial_port, app.get_pitch_roll_cmd); 
            res = fgetl(app.serial_port);
            delimiter = strfind(res,',');
            pitch = str2double(res(1:delimiter-1));
            roll = str2double(res(delimiter+1:end));


            app.all_pitch = [app.all_pitch pitch]; 
            app.all_roll = [app.all_roll roll]; 
            
            app.updateGyroPlot(pitch, roll); 
            
            %app.updateGyroTimePlot(); 
        end
       
        %==============================================================%
        %                   batteryUpdateTimerFunction
        % - Function to read battery voltage and calculate SOC
        %==============================================================%
        function results = batteryUpdateTimerFunction(app, ~, ~)
            
            try
                stop(app.serial_read_timer);   
            catch
               ; 
            end

            fwrite(app.serial_port, app.get_battery_cmd); 
            voltage = fgetl(app.serial_port);
            
            app.BatteryLevel.Text = strcat(voltage,'v');
            
            bat_voltage = str2double(voltage); 
            
            if (bat_voltage < 7)
                app.ConnectionStatusText.Text = 'WARNING: BATTERY VOLTAGE CRITICALLY LOW.';
                app.ConnectionStatusText_2.Text = 'UNEXPECTED BEHAVIOUR MAY OCCUR';
            else
                app.ConnectionStatusText.Text = 'Device OK';
                app.ConnectionStatusText_2.Text = strcat("ID: ", app.device_id);
            end
            
            start(app.serial_read_timer);   
        end
        
        function status = getID(app)
            app.serial_port.Timeout = 10;

            status = false; 
            
            fwrite(app.serial_port, app.get_id_cmd); 
          
           %app.device_id = fgetl(app.serial_port);
            res = fgetl(app.serial_port);
            delimiters = strfind(res,',')
            app.device_id = res(1:delimiters(1)-1);
            res_kp = round(str2double(res(delimiters(1)+1:delimiters(2)-1)),1);
            res_ki = round(str2double(res(delimiters(2)+1:delimiters(3)-1)),1);
            res_kd = round(str2double(res(delimiters(3)+1:delimiters(4)-1)),1);
            res_zero = round(str2double(res(delimiters(4)+1:end)),1);
            
            if (~isnan(res_kp))
                app.PEditField.Value = res_kp;
                app.IEditField.Value = res_ki;
                app.DEditField.Value = res_kd;
                app.SetPointEditField.Value = res_zero;
            else
                app.PEditField.Value = 0;
                app.IEditField.Value = 0;
                app.DEditField.Value = 0;
                app.SetPointEditField.Value = 0;
            end
            
            if (isempty(app.device_id) == 0)
                app.ConnectionStatusText.Text = 'Device OK';
                app.ConnectionStatusText_2.Text = strcat("ID: ", app.device_id);
                status = true;
            else
               app.ConnectionStatusText.Text = 'ERROR';
               app.ConnectionStatusText_2.Text = 'Did not get response from device' 
            end
            
            app.serial_port.Timeout = 1;
        end
        
        function data = downloadDataLogData(app, readings)
            stop(app.serial_read_timer);
            stop(app.battery_timer);
            app.serialReadTimerFunction();
           
            flushinput(app.serial_port);

            app.serial_port.Timeout = 10;
           
            fwrite(app.serial_port, app.download_data_cmd); 
            tmp = fgetl(app.serial_port);
            
            fwrite(app.serial_port, "OK" + newline); 
            
            while (app.serial_port.BytesAvailable == 0) end

            data = zeros(readings,3); 
            last_percentage = 0;
            for i = 1:readings
                res = fgetl(app.serial_port);
                delimiters = strfind(res,',');
                try
                    reading_num = str2double(res(1:delimiters(1)-1));
                    timestamp = str2double(res(delimiters(1)+1:delimiters(2)-1));
                    value = str2double(res(delimiters(2)+1:end))/2;
                catch
                    app.DataLogInfoText.Text = "Failed. Please try re-downloading"; 
                    return; 
                end
                data(i,1) = reading_num;
                data(i,2) = timestamp;
                data(i,3) = value;
                percent_complete = ((i/200)*100);
                if (percent_complete - last_percentage > 2.5)
                    app.DataLogInfoText.Text = strcat('Downloading: ',num2str(percent_complete), '%');
                    last_percentage = percent_complete;
                    drawnow
                end
            end
            
            app.DataLogInfoText.Text = 'Complete';

            excel_data = table(data(:,1), data(:,2), data(:,3))
            excel_data.Properties.VariableNames = {'Sample', 'Timestamp', 'Value'}
            
            app.data_log_table = excel_data;
            
            plot(app.UIAxesGyroTime, data(:,3));
            app.serial_port.Timeout = 11;
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Setup graph axes 
            app.UIAxesGyro.YLim = [-90, 90];
            app.UIAxesGyro.XLim = [-90, 90];
            
            % Plot default data on graphs 
            app.updateGyroPlot(0,0);
                      
            hold(app.UIAxesGyro);
        end

        % Callback function
        function ListBoxValueChanged(app, event)
            value = app.ListBox.Value;
            
            if (strcmp(value, 'P'))
               app.PEditField.Enable = true;
               app.IEditField.Enable = false;
               app.DEditField.Enable = false;
            elseif (strcmp(value, 'PI'))
               app.PEditField.Enable = true;
               app.IEditField.Enable = true;
               app.DEditField.Enable = false; 
            elseif (strcmp(value, 'PD'))
               app.PEditField.Enable = true;
               app.IEditField.Enable = false;
               app.DEditField.Enable = true;
            elseif (strcmp(value, 'PID'))
               app.PEditField.Enable = true;
               app.IEditField.Enable = true;
               app.DEditField.Enable = true;
            end
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            try
                stop(app.serial_read_timer);   
            catch
               ; 
            end
            
            app.ConnectionStatusText.Text = 'Connecting to port...'; 
            drawnow;
            
            instrreset();
            app.serial_port = udp('192.168.4.1', 2233, 'LocalPort', 2233);
            app.serial_port.Timeout = 1;
            
            % Try to open the port & catch errors
            try
                fopen(app.serial_port);
            catch ME
               disp("Error: " + ME.identifier);  
               app.PleaseConnecttoadeviceLamp.Color = [1 0 0];
               app.ConnectionStatusText.Text = 'Failed to connect.'; 
               return; 
            end
            
            %app.ConnectionStatusText.Text = 'Successfully connected to Egglet. Software version: '; 
            app.ConnectionStatusText.Text = 'Getting ID...'; 
            drawnow;
            
            if (app.getID() == true)
                app.PleaseConnecttoadeviceLamp.Color = [0 1 0];
                % Setup battery read timer
                app.battery_timer = timer('Period', 10, 'ExecutionMode', 'FixedSpacing', 'TasksToExecute', Inf, 'StartDelay', 0); 
                app.battery_timer.TimerFcn = @(~,~)app.batteryUpdateTimerFunction(app);
                
                % Setup MATLAB Timer at 100Hz to poll bluetooth device
                app.serial_read_timer = timer('Period', 0.1, 'ExecutionMode', 'FixedSpacing', 'TasksToExecute', Inf, 'StartDelay', 0); 
                app.serial_read_timer.TimerFcn = @(~,~)app.serialReadTimerFunction(app);
                
                start(app.battery_timer); 
            else
                app.PleaseConnecttoadeviceLamp.Color = [1 0 0];
            end
        end

        % Callback function
        function RefreshPortsButtonPushed(app, event)
            
        end

        % Button pushed function: UploadControllerButton
        function UploadControllerButtonPushed(app, event)
            app.uploadControllerStatus.Text = 'Sending...'
            drawnow;
            stop(app.serial_read_timer);
            
            app.serial_port.Timeout = 5;

            fwrite(app.serial_port, app.set_pid_cmd); 
            res = fgetl(app.serial_port);
            if (contains(res, 'SET PID VALUES'))
            
                Kp = num2str(app.PEditField.Value); 
                Ki = num2str(app.IEditField.Value);
                Kd = num2str(app.DEditField.Value); 

                cmd = strcat(Kp, ',', Ki, ',', Kd, newline); 
                fwrite(app.serial_port, cmd); 
                
                res = fgetl(app.serial_port);
                if (length(res) == 0)
                    app.uploadControllerStatus.Text = 'Failed: no response'
                    app.serial_port.Timeout = 1;
                    start(app.serial_read_timer);
                    return; 
                end
                delimiters = strfind(res,',')
                res_kp = round(str2double(res(1:delimiters(1)-1)),1);
                res_ki = round(str2double(res(delimiters(1)+1:delimiters(2)-1)),1);
                res_kd = round(str2double(res(delimiters(2)+1:end)),1);
                
                if (round(app.PEditField.Value,1) == res_kp && round(app.IEditField.Value,1) == res_ki && round(app.DEditField.Value,1) == res_kd)
                   app.uploadControllerStatus.Text = 'Success'
                else
                   app.uploadControllerStatus.Text = 'Failed: incorrect response'
                end
            else 
                app.uploadControllerStatus.Text = 'Failed: no response'   
            end
            drawnow;
            
            app.serial_port.Timeout = 1;

            start(app.serial_read_timer);
        end

        % Button pushed function: SetZeroButton
        function SetZeroButtonPushed(app, event)
            stop(app.serial_read_timer);
            
            app.serial_port.Timeout = 10;

            app.setZeroStatus.Text = 'Sending...'; 
            drawnow;
            
            fwrite(app.serial_port, app.set_zero_cmd); 
            res = fgetl(app.serial_port);
            
            if (contains(res, "SET ZERO"))
                setpoint = num2str(app.SetPointEditField.Value);
                cmd = strcat(setpoint, newline);
                fwrite(app.serial_port, cmd); 
                
                res = fgetl(app.serial_port); 
                
                if (abs(str2double(cmd) - str2double(res)) < 0.5)
                    app.setZeroStatus.Text = 'Complete';  
                else
                    app.setZeroStatus.Text = 'Failed: incorrect response';
                end
            else
                app.setZeroStatus.Text = 'Failed: no response';
            end

            app.serial_port.Timeout = 1;

            stop(app.serial_read_timer);
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            fwrite(app.serial_port, app.start_cmd);
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            fwrite(app.serial_port, app.stop_cmd);
        end

        % Button pushed function: StartDatalogButton
        function StartDatalogButtonPushed(app, event)
            stop(app.serial_read_timer);
            stop(app.battery_timer);
            app.serialReadTimerFunction();
            
            app.serial_port.Timeout = 10;
            
            app.DataLogInfoText.Text = 'Get ready... 3';
            drawnow
            pause(1); 
            app.DataLogInfoText.Text = 'Get ready... 2';
            drawnow
            pause(1); 
            app.DataLogInfoText.Text = 'Get ready... 1';
            drawnow
            pause(1);   
            
            app.DataLogInfoText.Text = 'Get ready... 3';
          
            fwrite(app.serial_port, app.start_datalog_cmd); 
            
            for i = 10:-1:1
                app.DataLogInfoText.Text = strcat(num2str(i), ' seconds remaining');
                drawnow
                pause(1); 
%                 if (app.serial_port.BytesAvailable > 0)
%                     break;
%                 end
            end
            
            app.DataLogInfoText.Text = 'Waiting for download...';
            drawnow
            while (app.serial_port.BytesAvailable == 0) end
           
            app.downloadDataLogData(app.num_readings);
            
            app.serial_port.Timeout = 1 ;
            %start(app.serial_read_timer);
        end

        % Button pushed function: SaveasExcelsheetButton
        function SaveasExcelsheetButtonPushed(app, event)
            [file,path] = uiputfile('*.xls');
            writetable(app.data_log_table, strcat(path, file));
        end

        % Button pushed function: RedownloadButton
        function RedownloadButtonPushed(app, event)
            if (app.num_readings > 0)
                app.downloadDataLogData(app.num_readings);
            else 
                app.DataLogInfoText.Text = "ERROR: No data to download";
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DynamicRoboticsControlCentreUIFigure = uifigure('Visible', 'off');
            app.DynamicRoboticsControlCentreUIFigure.Name = 'Dynamic Robotics Control Centre';
            app.ControllerPanel = uipanel(app.DynamicRoboticsControlCentreUIFigure);
            app.ControllerPanel.Title = 'Controller';
            app.UploadControllerButton = uibutton(app.ControllerPanel, 'push');
            app.UploadControllerButton.ButtonPushedFcn = createCallbackFcn(app, @UploadControllerButtonPushed, true);
            app.UploadControllerButton.Text = 'Upload Controller';
            app.PEditFieldLabel = uilabel(app.ControllerPanel);
            app.PEditFieldLabel.Text = 'P';
            app.PEditField = uieditfield(app.ControllerPanel, 'numeric');
            app.IEditFieldLabel = uilabel(app.ControllerPanel);
            app.IEditFieldLabel.Text = 'I';
            app.IEditField = uieditfield(app.ControllerPanel, 'numeric');
            app.DEditFieldLabel = uilabel(app.ControllerPanel);
            app.DEditFieldLabel.Text = 'D';
            app.DEditField = uieditfield(app.ControllerPanel, 'numeric');
            app.uploadControllerStatus = uilabel(app.ControllerPanel);
            app.uploadControllerStatus.Text = {'Set PID values and upload '; 'controller'};
            app.LiveDataPanel = uipanel(app.DynamicRoboticsControlCentreUIFigure);
            app.LiveDataPanel.Title = 'Live Data';
            app.UIAxesGyro = uiaxes(app.LiveDataPanel);
            title(app.UIAxesGyro, 'Gyroscope')
            xlabel(app.UIAxesGyro, 'X')
            ylabel(app.UIAxesGyro, 'Y')
            app.UIAxesGyroTime = uiaxes(app.LiveDataPanel);
            title(app.UIAxesGyroTime, 'Gyroscope With Time')
            xlabel(app.UIAxesGyroTime, 'Time')
            ylabel(app.UIAxesGyroTime, 'Gyro Value')
            app.DeviceConnectionPanel = uipanel(app.DynamicRoboticsControlCentreUIFigure);
            app.DeviceConnectionPanel.Title = 'Device Connection';
            app.ConnectButton = uibutton(app.DeviceConnectionPanel, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.PleaseConnecttoadeviceLamp = uilamp(app.DeviceConnectionPanel);
            app.ConnectionStatusText = uilabel(app.DeviceConnectionPanel);
            app.ConnectionStatusText.Text = 'Please connect to device';
            app.BatteryLabelDescriptor = uilabel(app.DeviceConnectionPanel);
            app.BatteryLabelDescriptor.Text = 'Battery:';
            app.BatteryLevel = uilabel(app.DeviceConnectionPanel);
            app.BatteryLevel.Text = '0%';
            app.ConnectionStatusText_2 = uilabel(app.DeviceConnectionPanel);
            app.ConnectionStatusText_2.Text = ' ';
            app.DataloggingPanel = uipanel(app.DynamicRoboticsControlCentreUIFigure);
            app.DataloggingPanel.Title = 'Data logging';
            app.StartDatalogButton = uibutton(app.DataloggingPanel, 'push');
            app.StartDatalogButton.ButtonPushedFcn = createCallbackFcn(app, @StartDatalogButtonPushed, true);
            app.StartDatalogButton.Text = 'Start';
            app.DataLogInfoText = uilabel(app.DataloggingPanel);
            app.DataLogInfoText.Text = 'Data logger not running';
            app.SaveasExcelsheetButton = uibutton(app.DataloggingPanel, 'push');
            app.SaveasExcelsheetButton.ButtonPushedFcn = createCallbackFcn(app, @SaveasExcelsheetButtonPushed, true);
            app.SaveasExcelsheetButton.Text = 'Save as Excel sheet';
            app.RedownloadButton = uibutton(app.DataloggingPanel, 'push');
            app.RedownloadButton.ButtonPushedFcn = createCallbackFcn(app, @RedownloadButtonPushed, true);
            app.RedownloadButton.Text = 'Re-download';
            app.ZeropointPanel = uipanel(app.DynamicRoboticsControlCentreUIFigure);
            app.ZeropointPanel.Title = 'Zero point';
            app.SetZeroButton = uibutton(app.ZeropointPanel, 'push');
            app.SetZeroButton.ButtonPushedFcn = createCallbackFcn(app, @SetZeroButtonPushed, true);
            app.SetZeroButton.Text = 'Set Zero';
            app.SetPointEditField = uieditfield(app.ZeropointPanel, 'numeric');
            app.setZeroStatus = uilabel(app.ZeropointPanel);
            app.setZeroStatus.Text = {'Set zero point in '; 'degrees'};
            app.MotorsPanel = uipanel(app.DynamicRoboticsControlCentreUIFigure);
            app.MotorsPanel.Title = 'Motors';
            app.StopButton = uibutton(app.MotorsPanel, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.StartButton = uibutton(app.MotorsPanel, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            % Show the figure after all components are created
            app.DynamicRoboticsControlCentreUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = robotControlGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DynamicRoboticsControlCentreUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DynamicRoboticsControlCentreUIFigure)
        end
    end
end